classdef mainapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        OptionsPanel                matlab.ui.container.Panel
        ExitButton                  matlab.ui.control.Button
        AboutappButton              matlab.ui.control.Button
        DiseasewikiButton_2         matlab.ui.control.Button
        ImageUploadButton_2         matlab.ui.control.Button
        PercentageofAffectedAreaTextArea  matlab.ui.control.TextArea
        PercentageofAffectedAreaTextAreaLabel  matlab.ui.control.Label
        CropConditionTextArea       matlab.ui.control.TextArea
        CropConditionTextAreaLabel  matlab.ui.control.Label
        LAKAPATILabel               matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DiseasewikiButton_2
        function DiseasewikiButton_2Pushed(app, event)
            diseasewiki
        end

        % Button pushed function: ImageUploadButton_2
        function ImageUploadButton_2Pushed(app, event)
            [baseFileName, folder] = uigetfile({'*.jpg'},'Select a image file');
            filepath = fullfile(folder,baseFileName);
            im = imread(filepath);
            imshow(im , 'Parent', app.UIAxes);
            [label,scores,imcam] = classifyimage(im);
            app.CropConditionTextArea.Value = label + newline + scores + " match";
            imshow(imcam, 'Parent', app.UIAxes_2);
            [seg_img, affectarea] = imageanalysis(im)
            imshow(seg_img,'Parent',app.UIAxes_3)
            app.PercentageofAffectedAreaTextArea.Value = affectarea * 100 + " %";
        end

        % Button pushed function: AboutappButton
        function AboutappButtonPushed(app, event)
            aboutusapp
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Heatmap of affected areas')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Processed Image')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logot.png');
            app.LAKAPATILabel = uilabel(app.UIFigure);
            app.LAKAPATILabel.Text = 'LAKAPATI';
            app.CropConditionTextAreaLabel = uilabel(app.UIFigure);
            app.CropConditionTextAreaLabel.Text = 'Crop Condition';
            app.CropConditionTextArea = uitextarea(app.UIFigure);
            app.CropConditionTextArea.Editable = 'off';
            app.CropConditionTextArea.Placeholder = 'Condition of Crops will display here';
            app.PercentageofAffectedAreaTextAreaLabel = uilabel(app.UIFigure);
            app.PercentageofAffectedAreaTextAreaLabel.Text = 'Percentage of Affected Area';
            app.PercentageofAffectedAreaTextArea = uitextarea(app.UIFigure);
            app.PercentageofAffectedAreaTextArea.Editable = 'off';
            app.PercentageofAffectedAreaTextArea.Placeholder = 'Percentage of affected areas will display here';
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.ImageUploadButton_2 = uibutton(app.OptionsPanel, 'push');
            app.ImageUploadButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImageUploadButton_2Pushed, true);
            app.ImageUploadButton_2.Text = 'Image Upload';
            app.DiseasewikiButton_2 = uibutton(app.OptionsPanel, 'push');
            app.DiseasewikiButton_2.ButtonPushedFcn = createCallbackFcn(app, @DiseasewikiButton_2Pushed, true);
            app.DiseasewikiButton_2.Text = 'Disease wiki';
            app.AboutappButton = uibutton(app.OptionsPanel, 'push');
            app.AboutappButton.ButtonPushedFcn = createCallbackFcn(app, @AboutappButtonPushed, true);
            app.AboutappButton.Text = 'About app';
            app.ExitButton = uibutton(app.OptionsPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end